classdef xTRCA_simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SamplingrateHzEditField         matlab.ui.control.EditField
        SamplingrateHzEditFieldLabel    matlab.ui.control.Label
        NumberofchannelsinternallyEditField  matlab.ui.control.EditField
        NumberofchannelsinternallyEditFieldLabel  matlab.ui.control.Label
        FixrandomnumbergeneratorseedCheckBox  matlab.ui.control.CheckBox
        GroundtruthmaxjittersEditField  matlab.ui.control.EditField
        GroundtruthmaxjittersEditFieldLabel  matlab.ui.control.Label
        xTRCAsimulatorLabel             matlab.ui.control.Label
        WhitenoisestdEditField          matlab.ui.control.EditField
        WhitenoisestdEditFieldLabel     matlab.ui.control.Label
        EstimatedmaxjittersEditField    matlab.ui.control.EditField
        EstimatedmaxjittersEditFieldLabel  matlab.ui.control.Label
        Jitterevalwinlo_shi_sEditField  matlab.ui.control.EditField
        Jitterevalwinlo_shi_sEditFieldLabel  matlab.ui.control.Label
        EpochedgessEditField            matlab.ui.control.EditField
        EpochedgessEditFieldLabel       matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        TriggeramplitudeEditField       matlab.ui.control.EditField
        TriggeramplitudeEditFieldLabel  matlab.ui.control.Label
        MeantriggerintervalsEditField   matlab.ui.control.EditField
        MeantriggerintervalsEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            % Author: Hirokazu Tanaka.
            % Reference: Tanaka and Miyakoshi (2019). Cross-correlation
            % task-related component analysis (xTRCA) for enhancing evoked
            % and induced responses of event-related potentials. NeuroImage
            % 197:177-190.
            %
            % History:
            % 09/04/2024 Makoto. Wrapped up to Matlab Apps. 
            % xx/xx/2019 Hiro. Created.


            % Fix the seed of the random number generator.
            if app.FixrandomnumbergeneratorseedCheckBox.Value == 1
                rng(0)
            end

            % Generate simulated data.
            EEG.srate = str2num(app.SamplingrateHzEditField.Value);
            simData      = randn(str2num(app.NumberofchannelsinternallyEditField.Value),101001)*str2num(app.WhitenoisestdEditField.Value);
            stimInterval = str2num(app.MeantriggerintervalsEditField.Value)*EEG.srate;
            stimLatency  = 1:stimInterval:size(simData,2);
            stimLatency([1 end]) = [];
            simData(:,stimLatency) = 1;

            % Generate a dummy EEG structure.
            EEG       = eeg_emptyset;
            EEG.data  = double(simData);
            EEG.srate = str2num(app.SamplingrateHzEditField.Value);
            EEG       = eeg_checkset(EEG);

            % Add jitters.
            maxJitterMs = str2num(app.GroundtruthmaxjittersEditField.Value)*EEG.srate; % +/- ms.
            for stimIdx = 1:length(stimLatency)
                EEG.event(stimIdx).latency = stimLatency(stimIdx) + round(rand(1,1)*maxJitterMs-maxJitterMs/2);
                EEG.event(stimIdx).type    = 'Trigger';
            end

            epochEdgesS  = str2num(app.EpochedgessEditField.Value);
            epochLengthS = diff(epochEdgesS);

            prestimBaselineLengthS = abs(epochEdgesS(1));
            jitterEvalWinS         = str2num(app.Jitterevalwinlo_shi_sEditField.Value);
            maxJitterS             = str2num(app.EstimatedmaxjittersEditField.Value);

            epochLengthSampUnit     = epochLengthS*EEG.srate;
            prestimBaselineSampUnit = prestimBaselineLengthS*EEG.srate;
            winOfInterestSampUnit   = diff(jitterEvalWinS)*EEG.srate;
            jitterLengthSampUnit    = round(maxJitterS*EEG.srate);

            if mod(jitterLengthSampUnit,2) == 1
                warning('jitterLengthSampUnit must be 2*n.')
                jitterLengthSampUnit = jitterLengthSampUnit+1;
            end

            % Obtain the timing vector.
            triggerIdx      = find(contains({EEG.event.type}, 'Trigger'));
            triggerTime     = round([EEG.event(triggerIdx).latency]);
            searchWinCenter = round(triggerTime + jitterEvalWinS(1)*EEG.srate + winOfInterestSampUnit/2);

            % Apply xTRCA.
            [searchWinCenter_xTRCA, w_xTRCA] = xTRCA(double(EEG.data), searchWinCenter, winOfInterestSampUnit, jitterLengthSampUnit, 10);

            % Calculate the amount of adjustment.
            t0_xTRCA_adjustment = searchWinCenter_xTRCA - searchWinCenter;

            % Oz. Need to epoch to the stim onset, not the jitter onset.
            chERP       = blockx(EEG.data(1,:), triggerTime                    -prestimBaselineSampUnit, epochLengthSampUnit);
            chERP_xTRCA = blockx(EEG.data(1,:), triggerTime+t0_xTRCA_adjustment-prestimBaselineSampUnit, epochLengthSampUnit);


            %% Plotting.

            % Obtain the plotting window's time range.
            plotTime = -prestimBaselineLengthS+1/EEG.srate:1/EEG.srate:epochLengthS-prestimBaselineLengthS;

            imagesc(app.UIAxes, plotTime, 1:size(chERP,1), chERP);
            colormap(app.UIAxes, 'gray')
            xlabel(app.UIAxes, 'Latency (s)')
            ylabel(app.UIAxes, 'Epoch index')
            line(app.UIAxes, [0 0], ylim, 'color', [0 0 0], 'linewidth', 2)
            title(app.UIAxes, 'Original')
            xlim(app.UIAxes, [-1*prestimBaselineLengthS epochLengthS-prestimBaselineLengthS])
            ylim(app.UIAxes, [1 100])

            imagesc(app.UIAxes_3, plotTime, 1:size(chERP_xTRCA,1), chERP_xTRCA)
            colormap(app.UIAxes_3, 'gray')
            xlabel(app.UIAxes_3, 'Latency (s)')
            ylabel(app.UIAxes_3, 'Epoch index')
            hold(app.UIAxes_3, "on")
            plot(app.UIAxes_3, t0_xTRCA_adjustment*1/EEG.srate, 1:size(chERP_xTRCA,1), 'color', [1 0 0], 'linewidth', 0.5)
            [~, triggerTimeIdx] = max(mean(chERP_xTRCA,1));
            numDetected = sum(chERP_xTRCA(:,triggerTimeIdx)>0.9);
            title(app.UIAxes_3, sprintf('xTRCA (%d/100 detected)', numDetected))
            hold(app.UIAxes_3, "off")
            xlim(app.UIAxes_3, [-1*prestimBaselineLengthS epochLengthS-prestimBaselineLengthS])
            ylim(app.UIAxes_3, [1 100])

            plot(app.UIAxes_2, plotTime, mean(chERP, 1))
            line(app.UIAxes_2, [0 0], [min(mean(chERP, 1)) max(mean(chERP, 1))], 'color', [0 0 0], 'linewidth', 0.5)
            title(app.UIAxes_2, 'Original')
            xlabel(app.UIAxes_2, 'Latency (s)')
            ylabel(app.UIAxes_2, 'Amplitude (\muV)')
            xlim(app.UIAxes_2, [min(plotTime) max(plotTime)])
            ylim(app.UIAxes_2, [min(mean(chERP, 1)) max(mean(chERP, 1))])

            plot(app.UIAxes_4, plotTime, mean(chERP_xTRCA,1))
            line(app.UIAxes_4, [0 0], [min(mean(chERP_xTRCA,1)) max(mean(chERP_xTRCA,1))], 'color', [0 0 0], 'linewidth', 0.5, 'linestyle', '--')
            title(app.UIAxes_4, sprintf('xTRCA (%d/100 detected)', numDetected))
            xlabel(app.UIAxes_4, 'Latency (s)')
            ylabel(app.UIAxes_4, 'Amplitude (\muV)')
            xlim(app.UIAxes_4, [min(plotTime) max(plotTime)])
            ylim(app.UIAxes_4, [min(mean(chERP_xTRCA, 1)) max(mean(chERP_xTRCA, 1))])


%%
            function [triggerLatencies_optimized, w] = xTRCA(inputData, triggerLatencies, windowLength, evalJitterWin, numIterations)

                % X: continuous data (N channels x T samplings)
                % t0: timing vector (K dims in sampling unit)
                % tau: block duration (scalar in sampling unit)
                % tsearch0: search range (scalar in sampling unit)
                % Q: maximum number of iterations

                % History
                % 09/04/2024 Makoto. Changed variable names to make them more descriptive.


                if nargin < 5
                    numIterations=5;
                end

                numTriggers = length(triggerLatencies);
                [w,lambda,Xb] = TRCA(inputData, triggerLatencies, windowLength);
                triggerLatencies_optimized = triggerLatencies;

                iterationIdx = 1;
                while iterationIdx<numIterations+1

                    lambdaold = lambda(1);

                    for triggerIdx = 1:numTriggers

                        % step 3.1: compute weight vector with given timings.
                        [w, lambda, Xb] = TRCA(  inputData,    triggerLatencies_optimized, windowLength);
                        [y,    yb,  tb] = blocky(inputData, w, triggerLatencies_optimized, windowLength);

                        % figure
                        % imagesc(yb)
                        %
                        % max(yb, [], 2)

                        disp([num2str((iterationIdx-1)*numTriggers+triggerIdx,'%03d') '-th computation, eigenvalue=' num2str(lambda(1))]);

                        % step 3.2: optimize timing with given weight vector.
                        tsearch = triggerLatencies(triggerIdx) + [-evalJitterWin/2:evalJitterWin/2]';
                        tb = repmat(tsearch, 1, windowLength) + repmat(0:windowLength-1, length(tsearch),1);
                        ykb = y(tb);
                        ykb = bsxfun(@minus, ykb, mean(ykb,2));
                        ybar = mean(yb([1:triggerIdx-1 triggerIdx+1:end], :));
                        dlambda = ykb*ybar'/windowLength;
                        [~, maxindex] = max(dlambda);
                        triggerLatencies_optimized(triggerIdx) = tsearch(maxindex);

                    end

                    % convergence check:
                    if abs(lambda(1)-lambdaold)/abs(lambdaold)<10^-4
                        break
                    end

                    iterationIdx = iterationIdx+1;
                end

                % subtract the center of mass:
                dt = triggerLatencies_optimized-triggerLatencies;
                dt = dt - mean(dt);
                triggerLatencies_optimized = round(triggerLatencies+dt);
            end


%%

            function [w,lambda,Xb,S,Q,U, V] = TRCA(X, t, tau)
            
                % X: continuous data (N channels x T samplings)
                % t: timing vector (K dims)
                % tau: block duration (scalar)
                %
                % History
                % 08/29/2024 Makoto. sign 
                
                [N, T] = size(X);
                K = length(t);
                
                % normalization:
                X = bsxfun(@minus, X, mean(X,2));
                X = bsxfun(@rdivide, X, std(X,[],2));
                
                Xb = zeros(N, tau, K);
                for k=1:K
                    Xb(:,:,k) = bsxfun(@minus, X(:,t(k):t(k)+tau-1), ...
                        mean(X(:,t(k):t(k)+tau-1),2)); 
                end
                
                % computation of S matrix:
                U = sum(Xb,3)/K;
                V = zeros(N,N);
                for k=1:K
                    V = V+Xb(:,:,k)*Xb(:,:,k)'/K;
                end
                S = K/(K-1)/tau*(U*U'-V/K);
                % S = K/(K-1)/tau*U*U';
                Q = X*X'/T;
                
                % eigendecomposition:
                [w, lambda] = eig(S, Q); 
                lambda = diag(lambda);
                [lambda, index] = sort(lambda, 'descend');
                w = w(:,index);
                
                % determining the sign (08/29/2024 Makoto.)
                Xb_2D = Xb(:,:);
                y = w(:,1)'*Xb_2D;
                [~,largestVarChIdx] = max(var(Xb_2D, 0, 2));
                sgn = sign(Xb_2D(largestVarChIdx,:)*y'); % correlation with Oz time series.
                w(:,1) = sgn*w(:,1);
                
                % % determining the sign:
                % y = w(:,1)'*X;
                % sgn = sign(X(29,:)*y'); % correlation with Oz time series.
                % w(:,1) = sgn*w(:,1);
            end


%%
            function [xb,  tb] = blockx(x,  t, tau)
                % blocking of y
                tb = repmat(t', 1, tau) + repmat(0:tau-1, length(t),1);
                xb = x(tb);
                xb = bsxfun(@minus, xb, mean(xb,2));
            end


%%
            function [y, yb, tb] = blocky(X, w, t, tau)
                % computation of 1st TRC (y)
                y = w(:,1)'*X;   
                y = y-mean(y); y = y/std(y);
                % y = sign(sum(y*X'))*y;  % this ensures the correct sign.
                
                % blocking of y
                tb = repmat(t', 1, tau) + repmat(0:tau-1, length(t),1);
                yb = y(tb);
                yb = bsxfun(@minus, yb, mean(yb,2));
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {850, 850};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {323, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MeantriggerintervalsEditFieldLabel = uilabel(app.LeftPanel);
            app.MeantriggerintervalsEditFieldLabel.Enable = 'off';
            app.MeantriggerintervalsEditFieldLabel.Text = 'Mean trigger interval [s]';
            app.MeantriggerintervalsEditField = uieditfield(app.LeftPanel, 'text');
            app.MeantriggerintervalsEditField.Enable = 'off';
            app.MeantriggerintervalsEditField.Value = '1';
            app.TriggeramplitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.TriggeramplitudeEditFieldLabel.Enable = 'off';
            app.TriggeramplitudeEditFieldLabel.Text = 'Trigger  amplitude';
            app.TriggeramplitudeEditField = uieditfield(app.LeftPanel, 'text');
            app.TriggeramplitudeEditField.Enable = 'off';
            app.TriggeramplitudeEditField.Value = '1';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EpochedgessEditFieldLabel = uilabel(app.LeftPanel);
            app.EpochedgessEditFieldLabel.Text = 'Epoch edges [s]';
            app.EpochedgessEditField = uieditfield(app.LeftPanel, 'text');
            app.EpochedgessEditField.Value = '[-0.2 0.2]';
            app.Jitterevalwinlo_shi_sEditFieldLabel = uilabel(app.LeftPanel);
            app.Jitterevalwinlo_shi_sEditFieldLabel.Text = 'Jitter eval win [lo_s hi_s]';
            app.Jitterevalwinlo_shi_sEditField = uieditfield(app.LeftPanel, 'text');
            app.Jitterevalwinlo_shi_sEditField.Value = '[-0.1 0.1]';
            app.EstimatedmaxjittersEditFieldLabel = uilabel(app.LeftPanel);
            app.EstimatedmaxjittersEditFieldLabel.Text = 'Estimated max jitter [s]';
            app.EstimatedmaxjittersEditField = uieditfield(app.LeftPanel, 'text');
            app.EstimatedmaxjittersEditField.Value = '0.2';
            app.WhitenoisestdEditFieldLabel = uilabel(app.LeftPanel);
            app.WhitenoisestdEditFieldLabel.Enable = 'off';
            app.WhitenoisestdEditFieldLabel.Text = 'White noise std';
            app.WhitenoisestdEditField = uieditfield(app.LeftPanel, 'text');
            app.WhitenoisestdEditField.Enable = 'off';
            app.WhitenoisestdEditField.Value = '0.1';
            app.xTRCAsimulatorLabel = uilabel(app.LeftPanel);
            app.xTRCAsimulatorLabel.Text = 'xTRCA simulator';
            app.GroundtruthmaxjittersEditFieldLabel = uilabel(app.LeftPanel);
            app.GroundtruthmaxjittersEditFieldLabel.Text = 'Ground-truth max jitter [s]';
            app.GroundtruthmaxjittersEditField = uieditfield(app.LeftPanel, 'text');
            app.GroundtruthmaxjittersEditField.Value = '0.10';
            app.FixrandomnumbergeneratorseedCheckBox = uicheckbox(app.LeftPanel);
            app.FixrandomnumbergeneratorseedCheckBox.Text = 'Fix random number generator seed';
            app.NumberofchannelsinternallyEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofchannelsinternallyEditFieldLabel.Enable = 'off';
            app.NumberofchannelsinternallyEditFieldLabel.Text = 'Number of  channels (internally)';
            app.NumberofchannelsinternallyEditField = uieditfield(app.LeftPanel, 'text');
            app.NumberofchannelsinternallyEditField.Enable = 'off';
            app.NumberofchannelsinternallyEditField.Value = '4';
            app.SamplingrateHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingrateHzEditFieldLabel.Enable = 'off';
            app.SamplingrateHzEditFieldLabel.Text = 'Sampling rate [Hz]';
            app.SamplingrateHzEditField = uieditfield(app.LeftPanel, 'text');
            app.SamplingrateHzEditField.Enable = 'off';
            app.SamplingrateHzEditField.Value = '1000';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xTRCA_simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end